classdef toolbox < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        MessageboxTextArea             matlab.ui.control.TextArea
        MessageboxTextAreaLabel        matlab.ui.control.Label
        saveMPH                        matlab.ui.control.Button
        saveSTL                        matlab.ui.control.Button
        compile                        matlab.ui.control.Button
        SpaceconstraintsPanel          matlab.ui.container.Panel
        map_confirm                    matlab.ui.control.Button
        UIAxes                         matlab.ui.control.UIAxes
        InputoutputmappingPanel        matlab.ui.container.Panel
        NumberofoutputsEditField       matlab.ui.control.NumericEditField
        NumberofoutputsEditFieldLabel  matlab.ui.control.Label
        NumberofinputsEditField        matlab.ui.control.NumericEditField
        Label                          matlab.ui.control.Label
        mapping_confirm                matlab.ui.control.Button
        UITable                        matlab.ui.control.Table
    end

    
    properties (Access = public)
        space % Description
        logics
        compiler_result
    end
    
    methods (Access = public)
        
    
    end
    
    methods (Access = private)
        
        function rectangleClickCallback(app, src, event)
            % Get the mouse click position relative to the rectangle
            point = get(app.UIAxes, 'CurrentPoint');
            x = floor(point(1, 1));
            y = floor(point(1, 2));

            app.space(y+1,x+1) = 1 - app.space(y+1,x+1);
            disp(app.space)
            position = [x,y,1,1];
            rect = rectangle('Parent', app.UIAxes, 'Position', position, 'FaceColor', (0.5*app.space(y+1,x+1)+0.5)*[1, 1, 1],'EdgeColor','none');
            set(rect, 'ButtonDownFcn', @(src, event) rectangleClickCallback(app, src, event));
        end
        

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: NumberofinputsEditField
        function NumberofinputsEditFieldValueChanged(app, event)
            a = app.NumberofinputsEditField.Value;
            b = app.NumberofoutputsEditField.Value;
            app.UITable.Data = zeros(2^a, a+b);
            tmp = split(string(char(65:65+a-1)),'');
            app.UITable.ColumnName = [tmp(2:end-1).' append('Q', string(1:b))];
            for i = 1:2^a
                for j = 1:a                    
                    app.UITable.Data(i,j) = rem(ceil(i/(2^(j-1)))+1,2);
                end
            end
            app.UITable.ColumnEditable(a+1:a+b) = true;
        end

        % Value changed function: NumberofoutputsEditField
        function NumberofoutputsEditFieldValueChanged(app, event)
            a = app.NumberofinputsEditField.Value;
            b = app.NumberofoutputsEditField.Value;
            app.UITable.Data = zeros(2^a, a+b);
            tmp = split(string(char(65:65+a-1)),'');
            app.UITable.ColumnName = [tmp(2:end-1).' append('Q', string(1:b))];
            for i = 1:2^a
                for j = 1:a                    
                    app.UITable.Data(i,j) = rem(ceil(i/(2^(j-1)))+1,2);
                end
            end
            app.UITable.ColumnEditable(a+1:a+b) = true;
           
        end

        % Button pushed function: mapping_confirm
        function mapping_confirmButtonPushed(app, event)
            total_logic = [];
            a = app.NumberofinputsEditField.Value;
            b = app.NumberofoutputsEditField.Value;
            for output_idx = b:-1:1
                truth_data = app.UITable.Data;
                syms A B C D E F G
                input_syms = [A, B, C, D, E, F, G];
                SOP = 0;


            %%%% SoP algorithm
                for i = 1:height(truth_data)
                    if truth_data(i,end-b+output_idx) ~= 0
                        Product = 1;
                        for j = 1:width(truth_data )-b
                            if truth_data(i, j) ~= 0
                                Product = Product & input_syms(j);
                            else
                                Product = Product & ~input_syms(j);
                            end
                        end
                        SOP = SOP | Product;
                    end
                end
                
                %%%% Simplify non-empty SoP
                if SOP ~= 0
                    SOP = simplify(SOP,'Steps',100);
                end
                if SOP == symtrue
                    SOP = 1;
                end
            total_logic = [total_logic;string(SOP)];  
            end
            app.logics = total_logic;
            disp(app.logics);
%             app.LogicExpression.Text = app.logics;
            postfix_array = In2Post(total_logic);
            [signalset, not_num, and_num, or_num] = getSignalset(postfix_array);
            default_map_size = ceil(sqrt(not_num + 2*and_num + 2*or_num)+1);
            app.UIAxes.XLim = [-0.01 default_map_size+0.01];
            app.UIAxes.YLim = [-0.01 default_map_size+0.01];
            app.UIAxes.Box = 'on';
            app.UIAxes.YDir = 'reverse';

            app.UIAxes.FontSize = 18;
            app.UIAxes.DataAspectRatio = [1, 1, 1];
            app.UIAxes.XTick = round(app.UIAxes.XLim(1)):1:round(app.UIAxes.XLim(2));
            app.UIAxes.YTick = round(app.UIAxes.YLim(1)):1:round(app.UIAxes.YLim(2));
            app.UIAxes.XTickLabel = string(app.UIAxes.XTick);
            app.UIAxes.YTickLabel = string(app.UIAxes.YTick);
            app.space = ones(default_map_size,default_map_size);
            position = [1 1 1 1];  % Define the position of the rectangle


%             map_vis = image(app.UIAxes,app.space);
%             map_vis.XData = map_vis.XData - 0.5;
%             map_vis.YData = map_vis.YData - 0.5;

        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            point = app.UIAxes.CurrentPoint;

            x = floor(point(1, 1));
            y = floor(point(1, 2));
            position = [x,y,1,1];
%             rect_name = append('rect','_','x','_','y');
            rect = rectangle('Parent', app.UIAxes, 'Position', position, 'FaceColor', [0.5, 0.5, 0.5],'EdgeColor','none');
            set(rect, 'ButtonDownFcn', @(src, event) rectangleClickCallback(app, src, event));
%             eval([rect_name ' = rect;']);
            app.space(y+1,x+1) = 1 - app.space(y+1,x+1);
            disp(app.space)
        end

        % Button pushed function: compile
        function compileButtonPushed(app, event)
            
            app.compiler_result = compile(app.MessageboxTextArea,app.UIAxes, app.logics, app.space);
        end

        % Button pushed function: saveSTL
        function saveSTLButtonPushed(app, event)
%             [fileName, filePath] = uigetfile();
%             if fileName ~= 0
% 
%                 fullPath = fullfile(filePath, fileName);
%                 [signalset, not_num, and_num, or_num] = getSignalset(app.logics);
%                 [signal_val, short_signalset] = splitSignalset(signalset);
%                 getSTL(app.compiler_result, short_signalset, app.space,not_num, and_num, or_num, fullPath);
%                 disp('STL saved to', fullPath);
%             end


            folderPath = uigetdir();
            if folderPath ~= 0
                % Step 2: Enter a file name
                prompt = 'Enter file name (without extension):';
                dlgTitle = 'Save File';
                defaultFileName = 'myfile';
                answer = inputdlg(prompt, dlgTitle, [1 50], {defaultFileName});
                
                if ~isempty(answer)
                    % Step 3: Combine folder path and file name
                    fileName = answer{1};
                    fileExt = '.stl';  % Modify the file extension as needed
                    fullFilePath = fullfile(folderPath, [fileName fileExt]);
                    
                    % Step 4: Save the file
                    postfix_array = In2Post(app.logics);
                    [signalset, not_num, and_num, or_num] = getSignalset(postfix_array);
                    [signal_val, short_signalset] = splitSignalset(signalset);
                    disp(['STL saved to', fullFilePath,'?']);
                    getSTL(app.compiler_result, short_signalset, app.space,not_num, and_num, or_num, fullFilePath);
                    app.MessageboxTextArea.Value = [app.MessageboxTextArea.Value; ['STL saved to', fullFilePath]];
                end
            end
        end

        % Button pushed function: saveMPH
        function saveMPHButtonPushed(app, event)
            folderPath = uigetdir();
            if folderPath ~= 0
                % Step 2: Enter a file name
                prompt = 'Enter file name (without extension):';
                dlgTitle = 'Save File';
                defaultFileName = 'myfile';
                answer = inputdlg(prompt, dlgTitle, [1 50], {defaultFileName});
                
                if ~isempty(answer)
                    % Step 3: Combine folder path and file name
                    fileName = answer{1};
                    fileExt = '.mph';  % Modify the file extension as needed
                    fullFilePath = fullfile(folderPath, [fileName fileExt]);
                    
                    % Step 4: Save the file
                    postfix_array = In2Post(app.logics);
                    [signalset, not_num, and_num, or_num] = getSignalset(postfix_array);
                    [signal_val, short_signalset] = splitSignalset(signalset);
                    disp(['MPH saved to', fullFilePath,'?']);
                    getMPH(app.compiler_result, short_signalset, app.space,not_num, and_num, or_num, fullFilePath);
                    disp(['MPH saved to', fullFilePath]);
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.InputoutputmappingPanel = uipanel(app.UIFigure);
            app.InputoutputmappingPanel.Title = 'Input-output mapping';
            app.UITable = uitable(app.InputoutputmappingPanel);
            app.UITable.ColumnName = '';
            app.UITable.RowName = {};
            app.mapping_confirm = uibutton(app.InputoutputmappingPanel, 'push');
            app.mapping_confirm.ButtonPushedFcn = createCallbackFcn(app, @mapping_confirmButtonPushed, true);
            app.mapping_confirm.Text = 'Confirm!';
            app.Label = uilabel(app.InputoutputmappingPanel);
            app.Label.Text = 'Number of inputs : ';
            app.NumberofinputsEditField = uieditfield(app.InputoutputmappingPanel, 'numeric');
            app.NumberofinputsEditField.ValueChangedFcn = createCallbackFcn(app, @NumberofinputsEditFieldValueChanged, true);
            app.NumberofoutputsEditFieldLabel = uilabel(app.InputoutputmappingPanel);
            app.NumberofoutputsEditFieldLabel.Text = 'Number of outputs: ';
            app.NumberofoutputsEditField = uieditfield(app.InputoutputmappingPanel, 'numeric');
            app.NumberofoutputsEditField.ValueChangedFcn = createCallbackFcn(app, @NumberofoutputsEditFieldValueChanged, true);
            app.SpaceconstraintsPanel = uipanel(app.UIFigure);
            app.SpaceconstraintsPanel.Title = 'Space constraints';
            app.UIAxes = uiaxes(app.SpaceconstraintsPanel);
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.map_confirm = uibutton(app.SpaceconstraintsPanel, 'push');
            app.map_confirm.Text = 'Confirm!';
            app.compile = uibutton(app.UIFigure, 'push');
            app.compile.ButtonPushedFcn = createCallbackFcn(app, @compileButtonPushed, true);
            app.compile.Text = 'Compile the circuit!';
            app.saveSTL = uibutton(app.UIFigure, 'push');
            app.saveSTL.ButtonPushedFcn = createCallbackFcn(app, @saveSTLButtonPushed, true);
            app.saveSTL.Text = 'Save STL';
            app.saveMPH = uibutton(app.UIFigure, 'push');
            app.saveMPH.ButtonPushedFcn = createCallbackFcn(app, @saveMPHButtonPushed, true);
            app.saveMPH.Text = 'Save MPH';
            app.MessageboxTextAreaLabel = uilabel(app.UIFigure);
            app.MessageboxTextAreaLabel.Text = 'Message box';
            app.MessageboxTextArea = uitextarea(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = toolbox

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end